classdef AnalizadorEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalizadorEMGUIFigure           matlab.ui.Figure
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        AutorCarlosLpezJimnezLabel_2    matlab.ui.control.Label
        V10Label                        matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        EstadoLabel                     matlab.ui.control.Label
        ArchivoLabel                    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GuardarButton                   matlab.ui.control.Button
        AnalizarButton                  matlab.ui.control.Button
        CargarButton                    matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ResultadosPanel                 matlab.ui.container.Panel
        Label_7                         matlab.ui.control.Label
        ResultadosTable                 matlab.ui.control.Table
        Panel_3                         matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        VistaDropDown                   matlab.ui.control.DropDown
        GrficaDropDownLabel             matlab.ui.control.Label
        Eje1                            matlab.ui.control.UIAxes
        Eje0                            matlab.ui.control.UIAxes
        AutorCarlosLpezJimnezLabel      matlab.ui.control.Label
        DeteccindecontraccinPanel       matlab.ui.container.Panel
        sLabel_5                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        hastaLabel                      matlab.ui.control.Label
        desdeLabel                      matlab.ui.control.Label
        TVentanasLabel                  matlab.ui.control.Label
        TVentanaField                   matlab.ui.control.NumericEditField
        TReposoFinField                 matlab.ui.control.NumericEditField
        SuavizadoLabel                  matlab.ui.control.Label
        TReposoIniField                 matlab.ui.control.NumericEditField
        KUmbralField                    matlab.ui.control.NumericEditField
        TRMSField                       matlab.ui.control.NumericEditField
        KUmbralFieldLabel               matlab.ui.control.Label
        DatosdelADCPanel                matlab.ui.container.Panel
        SPSLabel                        matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        BitsLabel                       matlab.ui.control.Label
        VrefLabel                       matlab.ui.control.Label
        SamplessLabel                   matlab.ui.control.Label
        SpsField                        matlab.ui.control.NumericEditField
        GananciaPGALabel                matlab.ui.control.Label
        BitsField                       matlab.ui.control.NumericEditField
        GananciaField                   matlab.ui.control.NumericEditField
        VrefField                       matlab.ui.control.NumericEditField
        FiltradoPanel                   matlab.ui.container.Panel
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        QField                          matlab.ui.control.NumericEditField
        FactordecalidadQEditFieldLabel  matlab.ui.control.Label
        FAltaField                      matlab.ui.control.NumericEditField
        CortesuperiorLabel              matlab.ui.control.Label
        FBajaField                      matlab.ui.control.NumericEditField
        CorteinferiorLabel              matlab.ui.control.Label
        F0Field                         matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel        matlab.ui.control.Label
        BandaCheck                      matlab.ui.control.CheckBox
        NotchCheck                      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        ch0      % Cuentas del ADC del canal 0 (del CSV)
        ch1      % Cuentas del ADC del canal 1 (del CSV)
        archivo  % Nombre del CSV cargado
        carpeta  % Carpeta del CSV cargado
        res      % Resultados de analizarEMG ([] hasta pulsar Analizar)
        carpetaGuardado  % Última carpeta elegida para guardar ([] al abrir la app)
    end
    
    methods (Access = private)
        
        function p = leerParametros(app)
            %LEERPARAMETROS Crea el struct p con los valores de la interfaz.
            %   Es el equivalente al bloque de parámetros del script.
            p.sps      = app.SpsField.Value;
            p.vRef     = app.VrefField.Value;
            p.ganancia = app.GananciaField.Value;
            p.nBitsADC = app.BitsField.Value;
            p.notchOn  = app.NotchCheck.Value;
            p.f0       = app.F0Field.Value;
            p.Q        = app.QField.Value;
            p.bandaOn  = app.BandaCheck.Value;
            p.fBanda   = [app.FBajaField.Value, app.FAltaField.Value];
            p.tVentana = app.TVentanaField.Value;
            p.tRMS     = app.TRMSField.Value;
            p.tReposo  = [app.TReposoIniField.Value, app.TReposoFinField.Value];
            p.kUmbral  = app.KUmbralField.Value;
        end

        function dibujarVista(app)
            %DIBUJARVISTA Dibuja en Eje0/Eje1 la gráfica elegida en el desplegable.
            %   No hace nada si todavía no se ha analizado.
            if isempty(app.res)
                return
            end
            ax = [app.Eje0, app.Eje1];
            for k = 1:2                     %Limpia restos de la vista anterior
                colorbar(ax(k), 'off');     %(la barra del espectrograma)
                legend(ax(k), 'off');
                cla(ax(k), 'reset');
            end
            %Posición de la opción elegida (1 a 7), así no dependemos de tildes
            vista = find(strcmp(app.VistaDropDown.Items, app.VistaDropDown.Value));
            switch vista
                case 1, graficarBruta(app.res, ax);
                case 2, graficarEspectro(app.res, ax);
                case 3, graficarNotch(app.res, ax);
                case 4, graficarBanda(app.res, ax);
                case 5, graficarFiltrada(app.res, ax);
                case 6, graficarEspectrograma(app.res, ax);
                case 7, graficarEnvolvente(app.res, ax);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            %Abre el explorador para elegir un CSV y guarda sus dos canales
            %Carpeta inicial: la última usada o, si no hay, data\raw del repo
            if ~isempty(app.carpeta)
                carpetaInicial = app.carpeta;
            else
                carpetaApp = fileparts(mfilename('fullpath'));  %Carpeta donde está la app
                carpetaInicial = fullfile(carpetaApp, '..', '..', 'data', 'raw');
                if ~isfolder(carpetaInicial)                     %Si no existe, carpeta actual
                    carpetaInicial = pwd;
                end
            end
            [f, c] = uigetfile("*.csv", "Elige la muestra EMG", carpetaInicial);
            figure(app.AnalizadorEMGUIFigure);           %Devuelve la app al frente (uigetfile la deja detrás)
            if isequal(f, 0)                %Se cerró sin elegir: no hacemos nada
                return
            end

            data = readtable(fullfile(c, f), 'VariableNamingRule', 'preserve');
            app.ch0 = data{:,2};            %Columna 2 porque la anterior es de tiempo
            app.ch1 = data{:,3};
            app.archivo = f;
            app.carpeta = c;
            app.res = [];                   %Muestra nueva: el análisis anterior ya no vale

            n = numel(app.ch0);
            app.ArchivoLabel.Text = sprintf('%s · %d muestras · %.1f s', ...
                f, n, n/app.SpsField.Value);
             app.ArchivoLabel.Enable = 'on';
            %Muestra nueva: todavía no está analizada
            app.EstadoLabel.Text = 'Sin analizar';
            app.EstadoLabel.FontColor = [0.5 0.5 0.5];
        end

        % Button pushed function: AnalizarButton
        function AnalizarButtonPushed(app, event)
            %Lee los parámetros, analiza la muestra cargada y muestra resultados
            if isempty(app.ch0)
                uialert(app.AnalizadorEMGUIFigure, 'Primero carga un archivo CSV.', 'Sin datos');
                return
            end
            p = leerParametros(app);
            try
                app.res = analizarEMG(app.ch0, app.ch1, p);
            catch err                       %P.ej. corte superior por encima de Nyquist
                %Análisis fallido: lo indicamos en la franja del archivo
                app.EstadoLabel.Text = '✗ Error en el análisis';
                app.EstadoLabel.FontColor = [0.80 0.15 0.15];
                uialert(app.AnalizadorEMGUIFigure, err.message, 'Error en el análisis');
                return
            end

            dibujarVista(app);

            r = app.res;                    %Tabla: una fila por resultado
            f = @(x) sprintf('%.1f', x);    %Número a texto con 1 decimal (47 -> '47.0')
            app.ResultadosTable.Data = {
                '50 Hz bruta (%)',      f(r.p50_ch0),       f(r.p50_ch1)
                '50 Hz tras notch (%)', f(r.p50notch_ch0),  f(r.p50notch_ch1)
                'RMS reposo (µV)',      f(r.rmsReposo_ch0), f(r.rmsReposo_ch1)
                'Umbral (µV)',          f(r.umbral0),       f(r.umbral1)};           
            %Análisis correcto: lo indicamos en la franja del archivo
            app.EstadoLabel.Text = '✓ Analizado';
            app.EstadoLabel.FontColor = [0.12 0.56 0.24];
            %Avisos de validarParametros: el análisis se hizo, pero conviene revisarlo
            if ~isempty(app.res.avisos)
                uialert(app.AnalizadorEMGUIFigure, strjoin(app.res.avisos, newline), ...
                    'Aviso', 'Icon', 'warning');
            end
        end

        % Value changed function: VistaDropDown
        function VistaDropDownValueChanged(app, event)
            dibujarVista(app);              %Redibuja con la vista elegida            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            %Guarda figuras, resultados, señales y filtros de la muestra analizada
            if isempty(app.res)
                uialert(app.AnalizadorEMGUIFigure, 'Primero analiza una muestra.', 'Nada que guardar');
                return
            end

            %Destino: lo elige el usuario, así la app funciona esté donde esté.
            %El diálogo abre en la última carpeta usada o, si no hay, en la del CSV
            if ~isempty(app.carpetaGuardado)
                carpetaInicial = app.carpetaGuardado;
            else
                carpetaInicial = app.carpeta;
            end
            carpetaDestino = uigetdir(carpetaInicial, 'Elige dónde guardar los resultados');
            figure(app.AnalizadorEMGUIFigure);      %Devuelve la app al frente
            if isequal(carpetaDestino, 0)           %Canceló: no guardamos
                return
            end
            app.carpetaGuardado = carpetaDestino;   %La recordamos para la próxima vez

            %Barra de espera mientras se crean y exportan las figuras (tarda unos s)
            d = uiprogressdlg(app.AnalizadorEMGUIFigure, 'Title', 'Guardando', ...
                'Message', 'Creando y exportando figuras...', 'Indeterminate', 'on');
            figs = crearFiguras(app.res, 'off');    %Las 7 figuras, ocultas
            try
                destino = guardarAnalisis(app.res, app.archivo, carpetaDestino, figs);
            catch err
                close(figs); close(d);
                uialert(app.AnalizadorEMGUIFigure, err.message, 'Error al guardar');
                return
            end
            close(figs); close(d);                  %Cerramos las ocultas para no acumularlas
            uialert(app.AnalizadorEMGUIFigure, sprintf('Guardado en:\n%s', destino), ...
                'Análisis guardado', 'Icon', 'success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AnalizadorEMGUIFigure = uifigure('Visible', 'off');
            app.AnalizadorEMGUIFigure.Name = 'Analizador EMG';
            app.AnalizadorEMGUIFigure.Icon = fullfile(pathToMLAPP, 'Blanco + azul claro fondo azul oscuro.png');
            app.FiltradoPanel = uipanel(app.AnalizadorEMGUIFigure);
            app.FiltradoPanel.Title = ' Filtrado';
            app.NotchCheck = uicheckbox(app.FiltradoPanel);
            app.NotchCheck.Text = 'Notch';
            app.NotchCheck.Value = true;
            app.BandaCheck = uicheckbox(app.FiltradoPanel);
            app.BandaCheck.Text = 'Pasa banda';
            app.BandaCheck.Value = true;
            app.FrecuenciaEditFieldLabel = uilabel(app.FiltradoPanel);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.F0Field = uieditfield(app.FiltradoPanel, 'numeric');
            app.F0Field.Tooltip = {'50 Hz en Europa, 60 Hz en América.'};
            app.F0Field.Value = 50;
            app.CorteinferiorLabel = uilabel(app.FiltradoPanel);
            app.CorteinferiorLabel.Text = 'Corte inferior';
            app.FBajaField = uieditfield(app.FiltradoPanel, 'numeric');
            app.FBajaField.Tooltip = {'Frecuencia de corte inferior'};
            app.FBajaField.Value = 20;
            app.CortesuperiorLabel = uilabel(app.FiltradoPanel);
            app.CortesuperiorLabel.Text = 'Corte superior';
            app.FAltaField = uieditfield(app.FiltradoPanel, 'numeric');
            app.FAltaField.Tooltip = {'Frecuencia de corte superior'};
            app.FAltaField.Value = 130;
            app.FactordecalidadQEditFieldLabel = uilabel(app.FiltradoPanel);
            app.FactordecalidadQEditFieldLabel.Text = 'Factor de calidad (Q)';
            app.QField = uieditfield(app.FiltradoPanel, 'numeric');
            app.QField.Tooltip = {'Factor de calidad del notch: cuanto mayor, más estrecha la banda que se elimina'};
            app.QField.Value = 35;
            app.HzLabel = uilabel(app.FiltradoPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.FiltradoPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.FiltradoPanel);
            app.HzLabel_3.Text = 'Hz';
            app.DatosdelADCPanel = uipanel(app.AnalizadorEMGUIFigure);
            app.DatosdelADCPanel.Title = '  Datos del ADC';
            app.VrefField = uieditfield(app.DatosdelADCPanel, 'numeric');
            app.VrefField.Tooltip = {'2.4 V con referencia del ADC.'};
            app.VrefField.Value = 2.4;
            app.GananciaField = uieditfield(app.DatosdelADCPanel, 'numeric');
            app.GananciaField.Value = 4;
            app.BitsField = uieditfield(app.DatosdelADCPanel, 'numeric');
            app.BitsField.Tooltip = {'16 bits en el ADS1198.'};
            app.BitsField.Value = 16;
            app.GananciaPGALabel = uilabel(app.DatosdelADCPanel);
            app.GananciaPGALabel.Text = 'Ganancia PGA';
            app.SpsField = uieditfield(app.DatosdelADCPanel, 'numeric');
            app.SpsField.Tooltip = {'Muestras por segundo del ADC'};
            app.SpsField.Value = 500;
            app.SamplessLabel = uilabel(app.DatosdelADCPanel);
            app.SamplessLabel.Text = 'Samples/s';
            app.VrefLabel = uilabel(app.DatosdelADCPanel);
            app.VrefLabel.Text = 'Vref ';
            app.BitsLabel = uilabel(app.DatosdelADCPanel);
            app.BitsLabel.Text = 'Bits';
            app.VLabel_2 = uilabel(app.DatosdelADCPanel);
            app.VLabel_2.Text = 'V';
            app.SPSLabel = uilabel(app.DatosdelADCPanel);
            app.SPSLabel.Text = 'SPS';
            app.DeteccindecontraccinPanel = uipanel(app.AnalizadorEMGUIFigure);
            app.DeteccindecontraccinPanel.Title = ' Detección de contracción';
            app.KUmbralFieldLabel = uilabel(app.DeteccindecontraccinPanel);
            app.KUmbralFieldLabel.Text = 'Sensibilidad K';
            app.TRMSField = uieditfield(app.DeteccindecontraccinPanel, 'numeric');
            app.TRMSField.Tooltip = {'Duración de la ventana de la envolvente RMS. Más larga suaviza más, pero responde más lento.'};
            app.TRMSField.Value = 0.2;
            app.KUmbralField = uieditfield(app.DeteccindecontraccinPanel, 'numeric');
            app.KUmbralField.Tooltip = {'Umbral = media del reposo + k · desviación típica. Un k menor detecta más, pero da más falsas activaciones'};
            app.KUmbralField.Value = 3;
            app.TReposoIniField = uieditfield(app.DeteccindecontraccinPanel, 'numeric');
            app.TReposoIniField.Value = 0.5;
            app.SuavizadoLabel = uilabel(app.DeteccindecontraccinPanel);
            app.SuavizadoLabel.Text = 'Suavizado RMS';
            app.TReposoFinField = uieditfield(app.DeteccindecontraccinPanel, 'numeric');
            app.TReposoFinField.Value = 2.5;
            app.TVentanaField = uieditfield(app.DeteccindecontraccinPanel, 'numeric');
            app.TVentanaField.Tooltip = {'Duración de cada trozo de señal con el que se calcula el espectrograma. Una ventana corta (0.1 s) ve mejor cuándo empieza y acaba cada contracción, pero distingue peor las frecuencias. Una ventana larga (0.5 s) hace lo contrario. Recomendado: 0.25 s. Solo afecta a la gráfica del espectrograma.'};
            app.TVentanaField.Value = 0.25;
            app.TVentanasLabel = uilabel(app.DeteccindecontraccinPanel);
            app.TVentanasLabel.Text = 'Ventana espectrograma';
            app.desdeLabel = uilabel(app.DeteccindecontraccinPanel);
            app.desdeLabel.Text = 'Reposo desde';
            app.hastaLabel = uilabel(app.DeteccindecontraccinPanel);
            app.hastaLabel.Text = 'Reposo hasta';
            app.sLabel = uilabel(app.DeteccindecontraccinPanel);
            app.sLabel.Text = 's';
            app.sLabel_3 = uilabel(app.DeteccindecontraccinPanel);
            app.sLabel_3.Text = 's';
            app.sLabel_4 = uilabel(app.DeteccindecontraccinPanel);
            app.sLabel_4.Text = 's';
            app.sLabel_5 = uilabel(app.DeteccindecontraccinPanel);
            app.sLabel_5.Text = 's';
            app.AutorCarlosLpezJimnezLabel = uilabel(app.AnalizadorEMGUIFigure);
            app.AutorCarlosLpezJimnezLabel.Text = 'Autor: Carlos López Jiménez';
            app.Panel_3 = uipanel(app.AnalizadorEMGUIFigure);
            app.Eje0 = uiaxes(app.Panel_3);
            title(app.Eje0, 'Canal 0')
            app.Eje1 = uiaxes(app.Panel_3);
            title(app.Eje1, 'Canal 1')
            app.GrficaDropDownLabel = uilabel(app.Panel_3);
            app.GrficaDropDownLabel.Text = 'Gráfica';
            app.VistaDropDown = uidropdown(app.Panel_3);
            app.VistaDropDown.Items = {'Señal bruta', 'Espectro', 'Notch (antes/después)', 'Paso banda (antes/después)', 'Bruta vs filtrada', 'Espectrograma', 'Envolvente RMS y umbral'};
            app.VistaDropDown.ValueChangedFcn = createCallbackFcn(app, @VistaDropDownValueChanged, true);
            app.VistaDropDown.Value = 'Señal bruta';
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '';
            app.ResultadosPanel = uipanel(app.AnalizadorEMGUIFigure);
            app.ResultadosPanel.Title = '    Resultados';
            app.ResultadosTable = uitable(app.ResultadosPanel);
            app.ResultadosTable.ColumnName = {'Resultado'; 'Canal 0'; 'Canal 1'};
            app.ResultadosTable.RowName = {};
            app.Label_7 = uilabel(app.ResultadosPanel);
            app.Label_7.Text = '';
            app.Panel = uipanel(app.AnalizadorEMGUIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Blanco + azul claro sin fondo.png');
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Analizador de señales EMG';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'BIOMEC UMA · Tecnología que devuelve autonomía';
            app.CargarButton = uibutton(app.Panel, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.AnalizarButton = uibutton(app.Panel, 'push');
            app.AnalizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButtonPushed, true);
            app.AnalizarButton.Text = 'Analizar';
            app.GuardarButton = uibutton(app.Panel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Panel_2 = uipanel(app.AnalizadorEMGUIFigure);
            app.ArchivoLabel = uilabel(app.Panel_2);
            app.ArchivoLabel.Enable = 'off';
            app.ArchivoLabel.Text = 'Ningún archivo';
            app.EstadoLabel = uilabel(app.Panel_2);
            app.EstadoLabel.Text = 'Sin analizar';
            app.V10Label = uilabel(app.AnalizadorEMGUIFigure);
            app.V10Label.Text = 'V1.0';
            app.AutorCarlosLpezJimnezLabel_2 = uilabel(app.AnalizadorEMGUIFigure);
            app.AutorCarlosLpezJimnezLabel_2.Text = 'Autor: Carlos López Jiménez';
            app.Label_4 = uilabel(app.AnalizadorEMGUIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.AnalizadorEMGUIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.AnalizadorEMGUIFigure);
            app.Label_6.Text = '';
            % Show the figure after all components are created
            app.AnalizadorEMGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalizadorEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalizadorEMGUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalizadorEMGUIFigure)
        end
    end
end